classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        CreateexportfilefirstLabel    matlab.ui.control.Label
        createexportfileButton        matlab.ui.control.Button
        loadmeasurementfirstLabel     matlab.ui.control.Label
        CurrentmeasurementLabel       matlab.ui.control.Label
        SelectHRVvariableButtonGroup  matlab.ui.container.ButtonGroup
        DFAalpha1Button               matlab.ui.control.RadioButton
        LFHFPowerFFTButton            matlab.ui.control.RadioButton
        StressindexButton             matlab.ui.control.RadioButton
        RMSSDButton                   matlab.ui.control.RadioButton
        LoadmeasurementButton         matlab.ui.control.Button
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
    end

    
    properties (Access = private)
        data % the mat-file that has been loaded
        setup % the setup mat-file
        createSampleFile % the popup to create sample config from
    end
    
    methods (Access = public)
        
        function importdata(~, sampleStart, sampleLength, fileLength, ...
                sampleShift, filename, path)
             if ~createCSV(sampleStart, sampleLength, fileLength, ...
                 sampleShift, filename, path)
                f = msgbox("Something went wrong, please try again!");
             end
             if isunix
                [status, cmdout] = system("cd /; open Applications/" + ...
                    "KubiosHRVPremium/application/KubiosHRVPremium.app");
             end
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setupApp(app)
        
              app.setup = setupHRVvariables();
              
%% TODO: add buttons dynamically, so its easier to add HRV variables
%             vars = app.setup.result.short;
%             buttonGrp = app.SelectHRVvariableButtonGroup;
% 
%             for idx = 1:length(vars)
%                 % TODO: fix the relative pos
%                 ypos = buttonGrp.Position(2) + idx * 15;
%                 rb = uiradiobutton(buttonGrp, 'Position', [5 ypos 123 316]);
%                 rb.Text = vars(idx);
%             end
        end

        % Button pushed function: LoadmeasurementButton
        function selectFile(app, event)
            % deactivate buttons while loading file
            radioButtons = get(app.SelectHRVvariableButtonGroup, 'Children');
            set(radioButtons, 'Enable', 'off');

            fig = app.UIFigure;
            msg = ['Do you want to load an export with or without ...' ...
                ' manually configured samples?'];
            title = 'Choose file configuration';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'With samples','Without samples','Cancel'}, ...
           'DefaultOption',2,'CancelOption',3);
            
            if ~strcmp(selection, 'Cancel')
                [file, path] = uigetfile('*.mat');
                path2file = fullfile(path, file);
                if strcmp(selection, 'Without samples')
                    app.data = WithoutSamplesParser(path2file);
                else
                    app.data = WithSamplesParser(path2file);
                end
            end

            app.selectHRVvariable();

            % enable radio buttons after the file has been load
            set(radioButtons, 'Enable', 'on');
            % show name of selected measurement file
            set(app.loadmeasurementfirstLabel, 'Text', file);
        end

        % Selection changed function: SelectHRVvariableButtonGroup
        function selectHRVvariable(app, event)
            selectedButton = app.SelectHRVvariableButtonGroup.SelectedObject;
            selectedVar = selectedButton.Text;
            
            app.data.plotToAxes(app.setup, app.UIAxes, selectedVar);
        end

        % Close request function: UIFigure
        function checkIfSaved(app, event)
            fig = app.UIFigure;
            msg = ['Did you save your evaluation? Otherwise it will' ...
                ' not be saved.'];
            title = 'Evaluation saved?';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'Yes','No'}, 'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection, 'Yes')
                delete(app)
            end
        end

        % Button pushed function: createexportfileButton
        function createexportfileButtonPushed(app, event)
            app.createSampleFile = createSampleFile(app);
        end

        % Clicked callback: PushTool
        function savePlot(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(app.UIAxes, [filepath filename]);
            end
        end

        % Size changed function: UIFigure
        function updateAppLayout(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @checkIfSaved, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadmeasurementButton = uibutton(app.LeftPanel, 'push');
            app.LoadmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.LoadmeasurementButton.Text = 'Load measurement';
            app.SelectHRVvariableButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectHRVvariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectHRVvariable, true);
            app.SelectHRVvariableButtonGroup.Title = 'Select HRV variable';
            app.RMSSDButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.RMSSDButton.Enable = 'off';
            app.RMSSDButton.Text = 'RMSSD';
            app.RMSSDButton.Value = true;
            app.StressindexButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.StressindexButton.Enable = 'off';
            app.StressindexButton.Text = 'Stress index';
            app.LFHFPowerFFTButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.LFHFPowerFFTButton.Enable = 'off';
            app.LFHFPowerFFTButton.Text = 'LF/HF Power FFT';
            app.DFAalpha1Button = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.DFAalpha1Button.Enable = 'off';
            app.DFAalpha1Button.Text = 'DFA alpha1';
            app.CurrentmeasurementLabel = uilabel(app.LeftPanel);
            app.CurrentmeasurementLabel.Text = 'Current measurement:';
            app.loadmeasurementfirstLabel = uilabel(app.LeftPanel);
            app.loadmeasurementfirstLabel.Text = 'load measurement first';
            app.createexportfileButton = uibutton(app.LeftPanel, 'push');
            app.createexportfileButton.ButtonPushedFcn = createCallbackFcn(app, @createexportfileButtonPushed, true);
            app.createexportfileButton.Text = 'create export file';
            app.CreateexportfilefirstLabel = uilabel(app.LeftPanel);
            app.CreateexportfilefirstLabel.Text = 'Create export file first:';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @savePlot, true);
            app.PushTool.Icon = 'save-icon-free-11.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @setupApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end